classdef measurement_system < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ResultatsnumricsPanel       matlab.ui.container.Panel
        LabelTHC                    matlab.ui.control.Label
        LabelGain                   matlab.ui.control.Label
        LabelPot                    matlab.ui.control.Label
        RunButton                   matlab.ui.control.Button
        LabelFreqValue              matlab.ui.control.Label
        LabelDurValue               matlab.ui.control.Label
        DuracisSlider               matlab.ui.control.Slider
        DuracisSliderLabel          matlab.ui.control.Label
        FrecuenciaHzSlider          matlab.ui.control.Slider
        FrecuenciaHzLabel           matlab.ui.control.Label
        ModeDropDown                matlab.ui.control.DropDown
        ModeDropDownLabel           matlab.ui.control.Label
        TipusdesenyalDropDown       matlab.ui.control.DropDown
        TipusdesenyalDropDownLabel  matlab.ui.control.Label
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function RunButtonPushed(app, ~)
            % Obtenim paràmetres des del menú
            mode = app.ModeDropDown.Value;               % 'synthetic' o 'recorded'
            signal_type = app.TipusdesenyalDropDown.Value;
            
            FS = 44100;
            duration = app.DuracisSlider.Value;
            F = app.FrecuenciaHzSlider.Value;
            t = 0:(1/FS):duration-(1/FS);
        
            % Generació de la senyal sintètica
            switch signal_type
                case 'sine'
                    x = sin(2*pi*F*t)';
                case 'square'
                    x = square(2*pi*F*t)';
                case 'triangle'
                    x = 2 * abs(2 * mod(F * t, 1) - 1) - 1;
                    x = x';
                case 'chirp'
                    durationch = duration;
                    samples = durationch*FS;
                    fmi = (0:1:samples-1)'./(samples-1);
                    phi = pi*(20000/FS)*(0:samples-1)';
                    x = sin(phi.*fmi);
                case 'saturated'
                    x_sat = 5 * sin(2*pi*F*t);
                    x = max(min(x_sat, 1), -1);
                otherwise
                    uialert(app.UIFigure, 'Tipus de senyal no reconegut', 'Error');
                    return
            end
        
            % Gravació o assignació
            if strcmp(mode, 'recorded')
                rec = audiorecorder(FS, 24, 1);
                pause(1);
                recordblocking(rec, duration);
                y = getaudiodata(rec);
        
                % Tallar silenci inicial
                threshold = 0.02;
                start_idx = find(abs(y) > threshold, 1, 'first');
                if ~isempty(start_idx)
                    y = y(start_idx:end);
                end
            else
                y = x(:);
            end
        
            % Mostrar en el primer gràfic
            plot(app.UIAxes_3, (1:length(y))/FS, y);
            title(app.UIAxes_3, 'Senyal');
            xlabel(app.UIAxes_3, 'Temps (s)');
            ylabel(app.UIAxes_3, 'Amplitud');
        
            % FFT
            Y = fft(y);
            N = length(Y);
            mag = abs(Y(1:floor(N/2)));
            f = linspace(0, FS/2, length(mag));
            plot(app.UIAxes_2, f, 20*log10(mag + eps));
            title(app.UIAxes_2, 'Espectre');
            xlabel(app.UIAxes_2, 'Freq (Hz)');
            ylabel(app.UIAxes_2, 'Magnitud (dB)');
        
            % Potència
            P = mean(y.^2);
        
            % Guany
            if strcmp(mode, 'synthetic')
                xamp = y;
            else
                rec2 = audiorecorder(FS,24,1);
                sound(x, FS);
                pause(0.1)
                recordblocking(rec2, duration);
                xamp = getaudiodata(rec2);
            end
            G = 10*log10(mean(xamp.^2) / mean(x.^2));
        
            % THD
            [~, idx_fund] = max(mag);
            P1 = mag(idx_fund)^2;
            harmonics = zeros(1, 4);
            for k = 2:5
                idx = idx_fund * k;
                if idx <= length(mag)
                    harmonics(k-1) = mag(idx)^2;
                end
            end
            if P1 == 0
                THD_value = -Inf;
            else
                THD_value = 10*log10(sum(harmonics)/P1);
            end
        
            % Mostrar resultats
            app.LabelPot.Text = sprintf('Potència: %.2f', P);
            app.LabelGain.Text = sprintf('Guany: %.2f', G);
            app.LabelTHC.Text = sprintf('THD: %.2f dB', THD_value);
        end

        function FrecuenciaHzSliderValueChanged2(app, event)
            app.LabelFreqValue.Text = sprintf('%.0f Hz', app.FrecuenciaHzSlider.Value);
        end
        
        function DuracisSliderValueChanged2(app, event)
            app.LabelDurValue.Text = sprintf('%.2f s', app.DuracisSlider.Value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function RunButtonPushedFcn(app)
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.TipusdesenyalDropDownLabel = uilabel(app.UIFigure);
            app.TipusdesenyalDropDownLabel.Text = 'Tipus de senyal';
            app.TipusdesenyalDropDown = uidropdown(app.UIFigure);
            app.TipusdesenyalDropDown.Items = {'sine', 'square', 'triangle', 'chirp', 'saturated'};
            app.TipusdesenyalDropDown.Value = 'sine';
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.UIFigure);
            app.ModeDropDown.Items = {'synthetic', 'recorded'};
            app.ModeDropDown.Value = 'synthetic';
            app.FrecuenciaHzLabel = uilabel(app.UIFigure);
            app.FrecuenciaHzLabel.Text = 'Frecuencia (Hz)';
            app.FrecuenciaHzSlider = uislider(app.UIFigure);
            app.FrecuenciaHzSlider.Limits = [100 2000];
            app.FrecuenciaHzSlider.Value = 440;
            app.DuracisSliderLabel = uilabel(app.UIFigure);
            app.DuracisSliderLabel.Text = 'Duració (s)';
            app.DuracisSlider = uislider(app.UIFigure);
            app.DuracisSlider.Limits = [1 10];
            app.DuracisSlider.Value = 5;
            app.LabelDurValue = uilabel(app.UIFigure);
            app.LabelDurValue.Text = 'LabelDurValue';
            app.LabelFreqValue = uilabel(app.UIFigure);
            app.LabelFreqValue.Text = 'LabelFreqValue';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.Text = 'Run';
            app.ResultatsnumricsPanel = uipanel(app.UIFigure);
            app.ResultatsnumricsPanel.Title = 'Resultats numérics';
            app.LabelPot = uilabel(app.ResultatsnumricsPanel);
            app.LabelPot.Text = 'LabelPot';
            app.LabelGain = uilabel(app.ResultatsnumricsPanel);
            app.LabelGain.Text = 'LabelGain';
            app.LabelTHC = uilabel(app.ResultatsnumricsPanel);
            app.LabelTHC.Text = 'LabelTHC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = measurement_system

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @RunButtonPushedFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end