classdef amplificador < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        ExportFileButton            matlab.ui.control.Button
        f_triangularEditField       matlab.ui.control.NumericEditField
        f_triangularEditFieldLabel  matlab.ui.control.Label
        fcEditField                 matlab.ui.control.NumericEditField
        fcEditFieldLabel            matlab.ui.control.Label
        f_sinEditField              matlab.ui.control.NumericEditField
        f_sinEditFieldLabel         matlab.ui.control.Label
        Fs_AmpEditField             matlab.ui.control.NumericEditField
        Fs_AmpEditFieldLabel        matlab.ui.control.Label
        GEditField                  matlab.ui.control.NumericEditField
        GEditFieldLabel             matlab.ui.control.Label
        AmplifyButton               matlab.ui.control.Button
        SelectFileButton            matlab.ui.control.Button
        Label_Fs                    matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        GenerateSineButton          matlab.ui.control.Button
        UIAxes_y                    matlab.ui.control.UIAxes
        UIAxes_Y                    matlab.ui.control.UIAxes
        UIAxes_x                    matlab.ui.control.UIAxes
        UIAxes_X                    matlab.ui.control.UIAxes
        UIAxes_xr                   matlab.ui.control.UIAxes
        UIAxes_tri                  matlab.ui.control.UIAxes
        UIAxes_comp                 matlab.ui.control.UIAxes
        UIAxes_XR                   matlab.ui.control.UIAxes
        UIAxes_TRI                  matlab.ui.control.UIAxes
        UIAxes_YR                   matlab.ui.control.UIAxes
        UIAxes_yr                   matlab.ui.control.UIAxes
        UIAxes_COMP                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        isStereo;
        fs;
        x;
        x_stereo;
        y;
        y_stereo;
    end
    
    methods (Access = private)
        function y=lpf(app,fc,x,fs)
                N=100;
                wn=fc/(fs/2);
                n=-N/2:N/2;
                h=wn*sinc(wn*n);
                w=0.54-0.46*cos(2*pi*(0:N)/N); %hamming windows
                h=h.*w;
                h=h/sum(h);
                y=conv(x,h,'same');
         end
        
        function xr = manualResample(app,x, Fs_out, Fs_in)
            t_in=(0:length(x)-1)/Fs_in;
            t_out=(0:1/Fs_out:t_in(end))';
            
            xr=interp1(t_in,x,t_out,'spline');
        end

        function y = ampli_graf(app)

            Fs_Amp = app.Fs_AmpEditField.Value;
            xAmp=app.manualResample(app.x,Fs_Amp,app.fs);
            f_tri=app.f_triangularEditField.Value;
            G=app.GEditField.Value;
            
            N=length(xAmp);         %N muestras
            T=N/Fs_Amp;             %duracion
            t =linspace(0,T,N);     %eje temporal
            f=linspace(0,Fs_Amp,N); %eje frecuencial
            
            y1=1.05*app.triangular(f_tri,Fs_Amp,N);
            
            z1=app.comparador(xAmp, y1,G);
            
            
            yAmp=app.lpf(app.fcEditField.Value,z1,Fs_Amp);
            
            app.y=app.manualResample(yAmp,app.fs,Fs_Amp);
            
            t_lim=0.001; %plot hasta 0.001s
            
            plot(app.UIAxes_xr,t(1:t_lim*Fs_Amp),xAmp(1:t_lim*Fs_Amp));
            
            semilogx(app.UIAxes_XR,f,abs(fft(xAmp)));
           
            plot(app.UIAxes_tri,t(1:t_lim*Fs_Amp),y1(1:t_lim*Fs_Amp));
            
            semilogx(app.UIAxes_TRI,f,abs(fft(y1)));


            plot(app.UIAxes_comp,t,z1);
            
            semilogx(app.UIAxes_COMP,f,abs(fft(z1)));


            plot(app.UIAxes_yr,t(1:t_lim*Fs_Amp),yAmp(1:t_lim*Fs_Amp));
            
            semilogx(app.UIAxes_YR,f,abs(fft(yAmp)));
            
            N=length(app.y);         %N muestras
            T=N/app.fs;             %duracion
            t =linspace(0,T,N);     %eje temporal
            f=linspace(0,app.fs,N); %eje frecuencial
            plot(app.UIAxes_y,t(1:t_lim*app.fs),app.y(1:t_lim*app.fs));
            semilogx(app.UIAxes_Y,f,abs(fft(app.y)));


            y=app.y; %señal amplificada
        end
    
         % Enfinestrament temporal
        
        function comp=comparador(app,x,tri, G)
                comp=ones(1,length(x));
                for i=1:length(x)
                    if x(i)<tri(i)
                        comp(i)=-1*G;
                    else
                        comp(i)=1*G;
                    end
                end  
            end 
            
        function tri=triangular(app,f_tri,Fs_Amp,N)
                T=(1/f_tri)*Fs_Amp;
                tri=ones(1,N);
                for i=1:N
                    if mod(i,T)<T/2
                        tri(i)=mod(i,T)*4/T-1;
                    else
                        tri(i)=-4*mod(i-T/2,T)/T+1;
                    end
                end 
              
            end

        function showx(app)
            N=length(app.x);
            t=linspace(0,N/app.fs,N);
            f=linspace(0,app.fs,N);
            plot(app.UIAxes_x,t,app.x);
            semilogx(app.UIAxes_X,f,abs(fft(app.x)));
            
            app.UIAxes_XR.XLim=[20,app.Fs_AmpEditField.Value/2];
            app.UIAxes_TRI.XLim=[20,app.Fs_AmpEditField.Value/2];
            app.UIAxes_COMP.XLim=[20,app.Fs_AmpEditField.Value/2];
            app.UIAxes_YR.XLim=[20,app.Fs_AmpEditField.Value/2];
            app.UIAxes_X.XLim=[20,app.Fs_AmpEditField.Value/2];
            app.UIAxes_Y.XLim=[20,app.Fs_AmpEditField.Value/2];
        end
        
        function y=ampli_en_seg(app)
            block_size=10000;
            overlap=block_size/2;
            [bloques, ventanas] = app.segmentar_hamming(app.x, block_size, overlap);
            for i=1:length(bloques)
                app.x=bloques(i,:);
                bloques(i,:)=app.ampli_graf();
            end
            y = app.ajuntar_hamming(bloques, ventanas, block_size, overlap, original_length);
        end
    end
    
    methods (Access = public)
        function y = ampli(app,x,fs)%stereo o mono, autodetercta
            
            if size(x,2)==2
                app.x=x(:,1);

            else
                app.x=x;

            end
            y=app.ampli_graf();
            
%             if(length(app.x)<10000)
%                 y=app.ampli_graf();
%             else
%                 y=app.ampli_en_seg();
%             end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AmplifyButton
        function AmplifyButtonPushed(app, event)
            if isempty(app.x)
                disp('input is empty');
            else
                app.ampli(app.x,app.fs);
            end
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [file, path]=uigetfile({'*.wav;*.mp3'});
            if isequal(file,0)
                disp("not found")
            else
            app.Label_Fs.Text=num2str(app.fs);
            [x,app.fs]=audioread(fullfile(path,file));
            if size(x,2)==2
                app.isStereo=true;
                app.x=x(:,1);

            else
                app.isStereo=false;
                app.x=x;
            end
            app.showx();
            
            end
        end

        % Button pushed function: GenerateSineButton
        function GenerateSineButtonPushed(app, event)
            f=app.f_sinEditField.Value;%hz
            T=1;%s
            app.fs=48000;
            app.x = sin(2*pi*f*(0:1/app.fs:T));
            
            app.showx();
            
        end

        % Button pushed function: ExportFileButton
        function ExportFileButtonPushed(app, event)
            if isempty(app.y)
                disp('output is empty')
            else
                [filename,pathname]=uiputfile('*.wav','save as');
                if isequal(filename,0)
                    disp('')
                else
                    fullpath=fullfile(pathname,filename);
                    audiowrite(fullpath,app.y,app.fs);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'º';
            app.UIAxes_COMP = uiaxes(app.Tab);
            title(app.UIAxes_COMP, 'FFT')
            xlabel(app.UIAxes_COMP, 'f (Hz)')
            ylabel(app.UIAxes_COMP, 'Y')
            zlabel(app.UIAxes_COMP, 'Z')
            app.UIAxes_COMP.XLim = [20 490000];
            app.UIAxes_yr = uiaxes(app.Tab);
            title(app.UIAxes_yr, 'Output')
            xlabel(app.UIAxes_yr, 't (s)')
            ylabel(app.UIAxes_yr, 'Y')
            zlabel(app.UIAxes_yr, 'Z')
            app.UIAxes_yr.XLim = [0 0.001];
            app.UIAxes_YR = uiaxes(app.Tab);
            title(app.UIAxes_YR, 'FFT')
            xlabel(app.UIAxes_YR, 'f (Hz)')
            ylabel(app.UIAxes_YR, 'Y')
            zlabel(app.UIAxes_YR, 'Z')
            app.UIAxes_YR.XLim = [20 490000];
            app.UIAxes_TRI = uiaxes(app.Tab);
            title(app.UIAxes_TRI, 'FFT')
            xlabel(app.UIAxes_TRI, 'f (Hz)')
            ylabel(app.UIAxes_TRI, 'Y')
            zlabel(app.UIAxes_TRI, 'Z')
            app.UIAxes_TRI.XLim = [20 490000];
            app.UIAxes_XR = uiaxes(app.Tab);
            title(app.UIAxes_XR, 'FFT')
            xlabel(app.UIAxes_XR, 'f (Hz)')
            ylabel(app.UIAxes_XR, 'Y')
            zlabel(app.UIAxes_XR, 'Z')
            app.UIAxes_XR.XLim = [20 490000];
            app.UIAxes_comp = uiaxes(app.Tab);
            title(app.UIAxes_comp, 'Comparator')
            xlabel(app.UIAxes_comp, 't (s)')
            ylabel(app.UIAxes_comp, 'Y')
            zlabel(app.UIAxes_comp, 'Z')
            app.UIAxes_comp.XLim = [0 0.001];
            app.UIAxes_tri = uiaxes(app.Tab);
            title(app.UIAxes_tri, 'Triangle wave')
            xlabel(app.UIAxes_tri, 't (s)')
            ylabel(app.UIAxes_tri, 'Y')
            zlabel(app.UIAxes_tri, 'Z')
            app.UIAxes_tri.XLim = [0 0.001];
            app.UIAxes_xr = uiaxes(app.Tab);
            title(app.UIAxes_xr, 'Input (resample to Fs__Amp)')
            xlabel(app.UIAxes_xr, 't (s)')
            ylabel(app.UIAxes_xr, 'Y')
            zlabel(app.UIAxes_xr, 'Z')
            app.UIAxes_xr.XLim = [0 0.001];
            app.UIAxes_X = uiaxes(app.Tab);
            title(app.UIAxes_X, 'FFT')
            xlabel(app.UIAxes_X, 'f (Hz)')
            ylabel(app.UIAxes_X, 'Y')
            zlabel(app.UIAxes_X, 'Z')
            app.UIAxes_X.XLim = [20 490000];
            app.UIAxes_x = uiaxes(app.Tab);
            title(app.UIAxes_x, 'Input')
            xlabel(app.UIAxes_x, 't (s)')
            ylabel(app.UIAxes_x, 'Y')
            zlabel(app.UIAxes_x, 'Z')
            app.UIAxes_x.XLim = [0 0.001];
            app.UIAxes_Y = uiaxes(app.Tab);
            title(app.UIAxes_Y, 'FFT')
            xlabel(app.UIAxes_Y, 'X')
            ylabel(app.UIAxes_Y, 'Y')
            zlabel(app.UIAxes_Y, 'Z')
            app.UIAxes_Y.XLim = [20 490000];
            app.UIAxes_y = uiaxes(app.Tab);
            title(app.UIAxes_y, 'Output (resample to input__fs)')
            xlabel(app.UIAxes_y, 'X')
            ylabel(app.UIAxes_y, 'Y')
            zlabel(app.UIAxes_y, 'Z')
            app.UIAxes_y.XLim = [0 0.001];
            app.GenerateSineButton = uibutton(app.Tab, 'push');
            app.GenerateSineButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSineButtonPushed, true);
            app.GenerateSineButton.Text = 'Generate Sine';
            app.Label = uilabel(app.Tab);
            app.Label.Text = 'input_fs:';
            app.Label_Fs = uilabel(app.Tab);
            app.Label_Fs.Text = '48000';
            app.SelectFileButton = uibutton(app.Tab, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.AmplifyButton = uibutton(app.Tab, 'push');
            app.AmplifyButton.ButtonPushedFcn = createCallbackFcn(app, @AmplifyButtonPushed, true);
            app.AmplifyButton.Text = 'Amplify';
            app.GEditFieldLabel = uilabel(app.Tab);
            app.GEditFieldLabel.Text = 'G:';
            app.GEditField = uieditfield(app.Tab, 'numeric');
            app.GEditField.Value = 0.5;
            app.Fs_AmpEditFieldLabel = uilabel(app.Tab);
            app.Fs_AmpEditFieldLabel.Text = 'Fs_Amp:';
            app.Fs_AmpEditField = uieditfield(app.Tab, 'numeric');
            app.Fs_AmpEditField.ValueDisplayFormat = '%.0f';
            app.Fs_AmpEditField.Value = 700000;
            app.f_sinEditFieldLabel = uilabel(app.Tab);
            app.f_sinEditFieldLabel.Text = 'f_sin:';
            app.f_sinEditField = uieditfield(app.Tab, 'numeric');
            app.f_sinEditField.ValueDisplayFormat = '%.0f';
            app.f_sinEditField.Value = 1000;
            app.fcEditFieldLabel = uilabel(app.Tab);
            app.fcEditFieldLabel.Text = 'fc';
            app.fcEditField = uieditfield(app.Tab, 'numeric');
            app.fcEditField.ValueDisplayFormat = '%.0f';
            app.fcEditField.Value = 20000;
            app.f_triangularEditFieldLabel = uilabel(app.Tab);
            app.f_triangularEditFieldLabel.Text = 'f_triangular';
            app.f_triangularEditField = uieditfield(app.Tab, 'numeric');
            app.f_triangularEditField.ValueDisplayFormat = '%.0f';
            app.f_triangularEditField.Value = 30000;
            app.ExportFileButton = uibutton(app.Tab, 'push');
            app.ExportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFileButtonPushed, true);
            app.ExportFileButton.Text = 'Export File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = amplificador

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end